classdef StimulateGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InitializeButton               matlab.ui.control.Button
        StartStimulationButton         matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        ResumeButton                   matlab.ui.control.Button
        SaveParametersButton           matlab.ui.control.Button
        TrialDurationEditFieldLabel    matlab.ui.control.Label
        TrialDurationEditField         matlab.ui.control.EditField
        SessionDurationEditFieldLabel  matlab.ui.control.Label
        SessionDurationEditField       matlab.ui.control.EditField
        AmplitudeMPaEditFieldLabel     matlab.ui.control.Label
        AmplitudeMPaEditField          matlab.ui.control.NumericEditField
        StimulusTypeEditFieldLabel     matlab.ui.control.Label
        StimulusTypeEditField          matlab.ui.control.EditField
        TrialNumEditFieldLabel         matlab.ui.control.Label
        TrialNumEditField              matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
   clear;

try, instrreset;
catch
end

frequency               = 258;                  % [kHz]
amplitudes              = [0.125 0.25 0.5 1 2];   % [MPa]
conversionVtoMPa        = 4;                    % 4MPa at 1 V
nRepetitions            = 10;

stimuli(1).dutycycle    = 20;           % [%]
stimuli(1).prf          = [10 150];     % [Hz]
stimuli(1).duration     = 1.5;          % [s]

stimuli(2).dutycycle    = 50;           % [%]
stimuli(2).prf          = [500];        % [Hz]
stimuli(2).duration     = 0.3;          % [s]

stimuli(3).dutycycle    = 100;          % [%]
stimuli(3).prf          = [0];          % [Hz]
stimuli(3).duration     = 0.3;          % [s]

inter_trial             = 10;           % [s]
durationBeforeStim      = 0.5;          % [s]
durationBuffer          = 0.001;        % [s]
frequencyBitSpeed       = 15;           % [Hz]

amplitudesmV            = amplitudes/conversionVtoMPa*1000;    % [mV]
amplitudesmV            = round(amplitudesmV);                 % round to get whole numbers

Parameters = allcomb(amplitudesmV,1:length(stimuli));
Parameters = repmat(Parameters,nRepetitions,1);
Parameters = Parameters(randperm(length(Parameters)),:);

bytesize = 10;
bytesize = min([nextpow2(max(max(Parameters))),bytesize]); % take maximum of needed bytesize and user-input bytesize

%DataVector = zeros(length(Parameters),bytesize*size(Parameters,2));
for ii = 1:length(Parameters)
    DataVector(ii,:) = binarize(Parameters(ii,:),[bytesize,2]);
end

estimatedTime = inter_trial*length(Parameters)/60; % [min]

app.TrialDurationEditField.Value = [num2str(inter_trial,'%d'),' seconds'];
app.SessionDurationEditField.Value = [num2str(floor(estimatedTime)),' min, ',num2str((estimatedTime-floor(estimatedTime))*60),' seconds'];

        end

        % Button pushed function: StartStimulationButton
        function StartStimulationButtonPushed(app, event)
FG_Mod_ID = 'MY52600694';
FG_Tx_ID  = 'MY52600670';

FG_Mod = visa('keysight',['USB0::0x0957::0x2A07::',FG_Mod_ID,'::0::INSTR']);
FG_Mod.OutputBufferSize = 2^32;
fopen(FG_Mod);
fprintf(FG_Mod,'*RST');

FG_Tx = visa('keysight',['USB0::0x0957::0x2A07::',FG_Tx_ID,'::0::INSTR']);
fopen(FG_Tx);
fprintf(FG_Tx ,'*RST');

fprintf(FG_Tx,['SOUR1:FREQ ',num2str(frequency*1e3)]); % set frequency
fprintf(FG_Tx,['SOUR1:VOLT ',num2str(amplitudesmV(1)*1e-3)]);

fprintf(FG_Tx,['SOUR1:AM:STAT 1']);
fprintf(FG_Tx, 'SOUR1:AM:DSSC 1');
fprintf(FG_Tx, 'SOUR1:AM:SOUR EXT');   % turn the source of AM modulation to channel 2

fprintf(FG_Tx,'SOUR2:VOLT 5');
fprintf(FG_Tx, 'SOUR2:VOLT:OFFS 2.5');     % 2.5V offset (0-5V)
fprintf(FG_Tx, 'SOUR2:FUNC SQU');          % turn to sq. wave
fprintf(FG_Tx, 'SOUR2:FUNC:SQU:DCYC 50');  % duty cycle of sq. wave is 50%
fprintf(FG_Tx, 'SOUR2:FREQ 7000');         % 7000Hz oscillating frequency

fprintf(FG_Tx, 'TRIG2:SOUR BUS');
fprintf(FG_Tx, 'SOUR2:BURS:STAT 0');       % turn burst off so that fast oscillation turns on

fprintf(FG_Mod,'DATA:VOL:CLE'); % clear volatile memory for arbitrary waveform
srate = 1000;

fprintf(FG_Mod,'SOUR1:FUNC ARB'); % Change channel 1's waveform to ARB
fprintf(FG_Mod,'SOUR1:FUNC:ARB:FILTER STEP');

fprintf(FG_Mod, ['DATA:ARB:DAC DC0',sprintf(',%d',round(zeros(8,1)*(2^15-1)))]); % smallest possible arbitraty waveform, to set triggering
fprintf(FG_Mod, ['DATA:ARB:DAC DCOFF',sprintf(',%d',round(zeros(0.5*srate,1)*(2^15-1)))]); % force off for 0.5s

for ii=1:length(stimuli)
    
    fprintf(FG_Mod,'SOUR1:FUNC ARB'); % Change channel 1's waveform to ARB
    fprintf(FG_Mod,'SOUR1:FUNC:ARB:FILTER STEP');
    
    arb = DCWithRiseAndFall(stimuli(ii).duration*srate,12,stimuli(ii).duration*1e3,'cosine');
    arbDAC = round(arb*(2^15 - 1));  % change to DAC values, signed, 16-bit
    arbname = ['arb',num2str(stimuli(ii).duration*1e3)];
    fprintf(FG_Mod,['DATA:ARB:DAC ',arbname,sprintf(',%d',arbDAC)]);
    
    SEQ_command = ['"','STIM',num2str(ii)','",',...
        '"DC0",',                                       '1,','repeatTilTrig,',  'maintain,','4,',... % first waveform
        '"arb',num2str(stimuli(ii).duration*1e3),'",',  '1,','once,',           'maintain,','4,',... % second waveform
        '"DCOFF",',                                     '1,','once,',           'maintain,','4,'];   % third waveform
    
    npoints = length(SEQ_command)-1; % number of points in the character vector
    ndigits = floor(log10(length(SEQ_command)))+1; % number of digits in the character vector
    
    fprintf(FG_Mod, ['DATA:SEQ #',... % print it to the function generator
        num2str(ndigits),...
        num2str(npoints),...
        SEQ_command]);
    fprintf(FG_Mod, ['SOUR1:FUNC:ARB STIM',num2str(ii)]);
    %fprintf(FG_Mod,['MMEM:STORE:DATA "INT:\PRFSweep.seq"']);
    fprintf(FG_Mod,['SOUR1:FUNC:ARB:SRATE ',num2str(srate)]);
    fprintf(FG_Mod,'SOUR1:VOLT 1');
    pause(1);
end

fprintf(FG_Mod, 'SOUR1:AM:STAT 1');
fprintf(FG_Mod, 'SOUR1:AM:SOUR CH2');
fprintf(FG_Mod, 'SOUR1:AM:DSSC OFF');  % turn DSSC off

fprintf(FG_Mod, 'SOUR2:VOLT 5');                        % voltage at 5 V (does not work at 3 V)
fprintf(FG_Mod, 'SOUR2:VOLT:OFFS 2.5');                 % offset to 2.5 V
fprintf(FG_Mod, 'SOUR2:FUNC SQU'                    );  % change to square wave


fprintf(FG_Mod,'TRIG1:SOUR BUS');
fprintf(FG_Mod,'TRIG2:SOUR BUS');

fprintf(FG_Mod, 'SOUR1:VOLT 2');

pause(1);

for ii=1:length(Parameters)
    tic;
    s1 = stimuli(Parameters(ii,2));
    fprintf(FG_Tx,['SOUR1:VOLT ',num2str(Parameters(ii,1)*1e-3)]);
    switch Parameters(ii,2)
        case 1
            
            app.TrialNumEditField.Value = ii;
            app.AmplitudeMPaEditField.Value = Parameters(ii,1)*1e-3*4;
            app.StimulusTypeEditField.Value = 'Swept';
            
            fprintf(FG_Mod, 'SOUR1:FUNC ARB');
            fprintf(FG_Mod,['SOUR1:FUNC:ARB STIM',num2str(Parameters(ii,2))]);
            
            fprintf(FG_Mod, 'SOUR1:AM:STAT 1');
            fprintf(FG_Mod, 'SOUR2:BURSt:STAT 0');
            fprintf(FG_Mod, ['SOUR2:FREQ ',num2str(s1.prf(1))]); % low frequency of sweep
            fprintf(FG_Mod, ['SOUR2:FUNC:SQU:DCYC ', num2str(s1.dutycycle)]);  % Duty Cycle (%)
            fprintf(FG_Mod, ['SOUR2:VOLT 5']); % 5 V
            fprintf(FG_Mod, ['SOUR2:SWE:SPAC LIN']); % sweep parameter - spacing
            fprintf(FG_Mod, ['SOUR2:SWE:TIME ',    num2str(s1.duration)]); % sweep parameter - duration
            fprintf(FG_Mod, ['SOUR2:FREQ:STAR ',   num2str(s1.prf(1))]); % sweep parameter - low frequency
            fprintf(FG_Mod, ['SOUR2:FREQ:STOP ',   num2str(s1.prf(2))]); % sweep parameter - high frequency
            fprintf(FG_Mod,  'SOUR2:SWE:HTIM 0.5'); % sweep parameter - hold time (corrects for differing times for sweep and arb)
            fprintf(FG_Mod,  'SOUR2:SWE:STAT 1');
        case 2
            
            app.TrialNumEditField.Value = ii;
            app.AmplitudeMPaEditField.Value = Parameters(ii,1)*1e-3*4;
            app.StimulusTypeEditField.Value = '500 Hz PRF';
            
            fprintf(FG_Mod, 'SOUR1:FUNC ARB');
            fprintf(FG_Mod,['SOUR1:FUNC:ARB STIM',num2str(Parameters(ii,2))]);
            fprintf(FG_Mod, 'SOUR1:AM:STAT 1');

            fprintf(FG_Mod,['SOUR2:FREQ ',          num2str(s1.prf)]);  % Modulating Frequency (Hz)
            fprintf(FG_Mod,['SOUR2:FUNC:SQU:DCYC ', num2str(s1.dutycycle)]);  % Duty Cycle (%)
            
            NCycles = floor(s1.prf*s1.duration*1.25);                        % Number of cycles
            fprintf(FG_Mod,['SOUR2:BURS:NCYC '      num2str(NCycles)]);
            fprintf(FG_Mod, 'SOUR2:BURS:STAT ON');                  % turn burst mode on
            
        case 3
            
            app.TrialNumEditField.Value = ii;
            app.AmplitudeMPaEditField.Value = Parameters(ii,1)*1e-3*4;
            app.StimulusTypeEditField.Value = 'Continuous';
            
            fprintf(FG_Mod, 'SOUR1:FUNC ARB');
            fprintf(FG_Mod,['SOUR1:FUNC:ARB STIM',num2str(Parameters(ii,2))]);
            fprintf(FG_Mod, 'SOUR1:AM:STAT 0');

        otherwise
            continue
    end
    
    fprintf(FG_Tx, 'SOUR2:BURS:STAT 0');       % turn burst on
    
    pause(durationBuffer);                % pause for buffer duration
    fprintf(FG_Tx,'OUTP2 ON');                 % turn on
    
    % write binary data
    DataByte = DataVector(ii,:); % current trial's parameter information
    for Bit=DataByte
        if Bit
            fprintf(FG_Tx,'SOUR2:FUNC DC'); % DC of 1
            pause(1/frequencyBitSpeed);
            fprintf(FG_Tx,'SOUR2:FUNC SQU'); % back to buzz
        else
            fprintf(FG_Tx, 'SOUR2:BURS:STAT 1'); % turn off
            pause(1/frequencyBitSpeed);
            fprintf(FG_Tx, 'SOUR2:BURS:STAT 0'); % turn back on
        end
    end
    
    pause(durationBuffer); % Ch2 offset is now set to zero for trial phase
    
    fprintf(FG_Tx,'OUTP2 OFF');
    fprintf(FG_Mod, 'SOUR1:VOLT 2.9');

    fprintf(FG_Tx, 'OUTP1 ON');
    fprintf(FG_Mod,'OUTP1 ON');
    
    pause(durationBeforeStim);
    fprintf(FG_Mod, '*TRG');
    
    pause(s1.duration + 0.5); % pause for longer than the duration of the stimulation
    fprintf(FG_Mod, 'OUTP1 OFF'); % turn transducer off
    fprintf(FG_Tx,  'OUTP1 OFF'); % turn modulation off
    
    t(ii) = toc;
    
    pause(inter_trial-t(ii)); % pause so that time between the starts of the trials are 5 seconds apart

end

        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            pmtrs = Parameters(ii,:);
            save('TrialParams.mat','pmtrs','-append');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StartStimulationButton = uibutton(app.UIFigure, 'push');
            app.StartStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartStimulationButtonPushed, true);
            app.StartStimulationButton.Text = 'Start Stimulation';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.Text = 'Resume';
            app.SaveParametersButton = uibutton(app.UIFigure, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.TrialDurationEditFieldLabel = uilabel(app.UIFigure);
            app.TrialDurationEditFieldLabel.Text = 'Trial Duration';
            app.TrialDurationEditField = uieditfield(app.UIFigure, 'text');
            app.SessionDurationEditFieldLabel = uilabel(app.UIFigure);
            app.SessionDurationEditFieldLabel.Text = 'Session Duration';
            app.SessionDurationEditField = uieditfield(app.UIFigure, 'text');
            app.AmplitudeMPaEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeMPaEditFieldLabel.Text = 'Amplitude (MPa)';
            app.AmplitudeMPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulusTypeEditFieldLabel = uilabel(app.UIFigure);
            app.StimulusTypeEditFieldLabel.Text = 'Stimulus Type';
            app.StimulusTypeEditField = uieditfield(app.UIFigure, 'text');
            app.TrialNumEditFieldLabel = uilabel(app.UIFigure);
            app.TrialNumEditFieldLabel.Text = 'Trial Num';
            app.TrialNumEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = StimulateGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end